classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SpeakerIdentificationSystemLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        DatabaseTab                  matlab.ui.container.Tab
        DatabaseButton               matlab.ui.control.Button
        Label_10                     matlab.ui.control.Label
        ImportEditField              matlab.ui.control.EditField
        ImportEditFieldLabel         matlab.ui.control.Label
        database                     matlab.ui.control.Table
        Label_9                      matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        Label_8                      matlab.ui.control.Label
        TrainingTab                  matlab.ui.container.Tab
        FeatureExtractionusingPitchandMFCCLabel  matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        ValidationaccuracyEditField  matlab.ui.control.EditField
        ValidationaccuracyLabel      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        mfccandpoft                  matlab.ui.control.Table
        TrainthesystemButton         matlab.ui.control.Button
        Label_6                      matlab.ui.control.Label
        TestingTab                   matlab.ui.container.Tab
        SelectButton                 matlab.ui.control.Button
        RecordingnotificationLabel   matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        DurationLabel                matlab.ui.control.Label
        xxsecLabel                   matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        IdentifythespeakerButton     matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        Label_5                      matlab.ui.control.Label
        importEditFieldtest          matlab.ui.control.EditField
        ImportLabel                  matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        TestingMethodButtonGroup     matlab.ui.container.ButtonGroup
        RecordButton                 matlab.ui.control.RadioButton
        BrowseButton_2               matlab.ui.control.RadioButton
        Label_3                      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        UIAxes                       matlab.ui.control.UIAxes
        settingTab                   matlab.ui.container.Tab
        guideButton                  matlab.ui.control.Button
        Label_12                     matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pathname % Description
        trainDatastore % Description
        trainedClassifier % Description
        mn % Description
        sd % Description
        pathname2 % Description
        testDatastore % Description
        methodvalue % Description
    end
    
    methods (Access = private)
        
        function [pitch1, mfcc1] = computePitchMFCC(app,x,fs)

pwrThreshold = -50; % Frames with power below this threshold (in dB) are likely to be silence
freqThreshold = 1000; % Frames with zero crossing rate above this threshold (in Hz) are likely to be silence or unvoiced speech

% Audio data will be divided into frames of 30 ms with 75% overlap
frameTime = 30e-3;
samplesPerFrame = floor(frameTime*fs);
startIdx = 1;
stopIdx = samplesPerFrame;
increment = floor(0.25*samplesPerFrame);
overlapLength = samplesPerFrame - increment;

[pitch1,~] = pitch(x,fs, ...
    'WindowLength',samplesPerFrame, ...
    'OverlapLength',overlapLength);

mfcc1 = mfcc(x,fs,'WindowLength',samplesPerFrame, ...
    'OverlapLength',overlapLength, 'LogEnergy', 'Replace');
numFrames = length(pitch1);
voicing = zeros(numFrames,1);

    for i = 1: numFrames
        
        xFrame = x(startIdx:stopIdx,1); % 30ms frame

        if audiopluginexample.SpeechPitchDetector.isVoicedSpeech(xFrame,fs,... % Determining if the frame is voiced speech
                pwrThreshold,freqThreshold)
            voicing(i) = 1;
        end
        startIdx = startIdx + increment;
        stopIdx = stopIdx + increment;

    
    end
    
pitch1(voicing == 0) = nan;
mfcc1(voicing == 0,:) = nan; 
        
        end


        function t = HelperComputePitchAndMFCC(app,data, info)


% HelperComputePitchAndMFCC Compute pitch and MFCC features
% This function performs the following actions 
% on the audio samples of the input file read using audio datastore 
% 1. Divide the audio into frames of size 30ms with overlap of 75%.
% 2. For each frame, determine if it contains voiced speech.
% 3. Compute pitch and 13 MFCCs.
% 4. Keep pitch and MFCC features only for voiced speech.

% The output of this function is a table containing filename, pitch, MFCCs,
% and speaker name as columns. The structure is an array because each file
% will have these values for multiple frames. NaNs indicate that the frame
% was not voiced speech.
%
% This function HelperComputePitchAndMFCC is only in support of
% SpeakerIdentificationExample. It may change in a future release.

%   Copyright 2017 The MathWorks, Inc.

fs = 16e3;

% Compute pitch and MFCC for frames of the file

[pitch1, mfcc1] = app.computePitchMFCC(data,fs);

filenamesplit = regexp(info.FileName, filesep, 'split');

% Output structure
s = struct();
s.Filename = repmat({filenamesplit{end}},size(pitch1));
s.Pitch = pitch1;
s.MFCC1 = mfcc1(:,1);
s.MFCC2 = mfcc1(:,2);
s.MFCC3 = mfcc1(:,3);
s.MFCC4 = mfcc1(:,4);
s.MFCC5 = mfcc1(:,5);
s.MFCC6 = mfcc1(:,6);
s.MFCC7 = mfcc1(:,7);
s.MFCC8 = mfcc1(:,8);
s.MFCC9 = mfcc1(:,9);
s.MFCC10 = mfcc1(:,10);
s.MFCC11 = mfcc1(:,11);
s.MFCC12 = mfcc1(:,12);
s.MFCC13 = mfcc1(:,13);
s.Label = repmat({char(info.Label)},size(pitch1));

t = struct2table(s);
    
        end


        function result = HelperTestKNNClassifier(app,trainedClassifier, featuresTest)
       
%HelperTestKNNClassifier Test a trained classifier
%
%  Input:
%      trainedClassifier : A trained KNN classifier
%      featuresTest      : A table containing the predictor (Pitch & MFCC)
%                          and response (speaker name) columns
%
%  Output:
%      result : A table containing the name, actual speaker, predicted
%               speaker, and confidence of prediction for each test file
%
% This function HelperTestKNNClassifier is only in support of
% SpeakerIdentificationExample. It may change in a future release.

%   Copyright 2017-2018 The MathWorks, Inc.

% Get groups of rows corresponding to each filename
[Fidx,Filenames] = findgroups(featuresTest.Filename);
result = table();

for idx = 1:length(Filenames)
    T = featuresTest(Fidx==idx,2:end);  % Rows that correspond to one file
    predictedLabels = string(predict(trainedClassifier,T(:,1:15))); % Predict
    totalVals = size(predictedLabels,1);

    [predictedLabel, freq] = mode(categorical(predictedLabels)); % Find most frequently predicted label
    match = freq/totalVals*100;

    result_file.Filename = Filenames(idx);
    result_file.ActualSpeaker = T.Label{1};
    result_file.PredictedSpeaker = char(predictedLabel);
    result_file.ConfidencePercentage = match;
    
    result_file.PredictedSpeaker = string(result_file.PredictedSpeaker);


    result = [result; struct2table(result_file)]; %#ok
end

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainthesystemButton
        function TrainthesystemButtonPushed(app, event)


lenDataTrain = length(app.trainDatastore.Files);
features = cell(lenDataTrain,1);
for i = 1:lenDataTrain
    [dataTrain, infoTrain] = read(app.trainDatastore);
    features{i} = app.HelperComputePitchAndMFCC(dataTrain,infoTrain);
end
features = vertcat(features{:});
features = rmmissing(features);
featureVectors = features{:,2:15};

app.mn = mean(featureVectors);
app.sd = std(featureVectors);
features{:,2:15} = (featureVectors-app.mn)./app.sd;


inputTable     = features;
predictorNames = features.Properties.VariableNames;
predictors     = inputTable(:, predictorNames(2:15));
response       = inputTable.Label;

app.trainedClassifier = fitcknn( ...
    predictors, ...
    response, ...
    'Distance','euclidean', ...
    'NumNeighbors',5, ...
    'DistanceWeight','squaredinverse', ...
    'Standardize',false, ...
    'ClassNames',unique(response));


k = 5;
group = response;
c = cvpartition(group,'KFold',k); % 5-fold stratified cross validation
partitionedModel = crossval(app.trainedClassifier,'CVPartition',c);

validationAccuracy = 1 - kfoldLoss(partitionedModel, 'LossFun', 'ClassifError');


validationPredictions = kfoldPredict(partitionedModel);

cm = confusionchart(features.Label,validationPredictions,'title','Validation Accuracy');
cm.ColumnSummary = 'column-normalized';
cm.RowSummary = 'row-normalized';
app.ValidationaccuracyEditField.Value=num2str(validationAccuracy*100);


app.mfccandpoft.Data=features;


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           app.pathname=uigetdir;
           app.ImportEditField.Value=app.pathname;
           
                 
app.trainDatastore = audioDatastore(app.pathname, 'IncludeSubfolders',true, ...
    'FileExtensions','.flac', ...
    'LabelSource','foldernames');

a=nominal(app.trainDatastore.Labels);
b=cellstr(a);

a1=nominal(app.trainDatastore.Files);
b1=cellstr(a1);

c=[b,b1];

app.database.Data=(c);
           app.UIFigure.Visible='off';
app.UIFigure.Visible='on';
           
        end

        % Button pushed function: IdentifythespeakerButton
        function IdentifythespeakerButtonPushed(app, event)


lenDataTest = length(app.testDatastore.Files);
featuresTest = cell(lenDataTest,1);
for i = 1:lenDataTest
  [dataTest, infoTest] = read(app.testDatastore);
  featuresTest{i} = app.HelperComputePitchAndMFCC(dataTest,infoTest);
end

featuresTest = vertcat(featuresTest{:});
featuresTest = rmmissing(featuresTest);


featuresTest{:,2:15} = (featuresTest{:,2:15}-app.mn)./app.sd;
resulttable= app.HelperTestKNNClassifier(app.trainedClassifier, featuresTest);
        
   
d2=table2cell(resulttable);


app.UITable.Data=cell2table(d2);
        
        
        end

        % Selection changed function: TestingMethodButtonGroup
        function TestingMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.TestingMethodButtonGroup.SelectedObject;
            app.methodvalue=selectedButton.Text;

               
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
            switch app.methodvalue
                case 'Browse'
                   cla(app.UIAxes);
                    app.UITable.Data={};
                    app.Label_7.Text=('');
                    app.importEditFieldtest.Value=('');
                      app.pathname2=uigetdir;
                      app.importEditFieldtest.Value=app.pathname2;
      
                case 'Record'
                     cla(app.UIAxes);
                     app.importEditFieldtest.Value=('');
                      app.Label_7.Text=('');
                    app.UITable.Data={};
                    recObj = audiorecorder;
                     app.Label_7.Text=('Start speaking.');
                    recordblocking(recObj, 5);
                     app.Label_7.Text=('End of Recording.');
                    y = getaudiodata(recObj);
                    audiowrite('C:\Users\ossa7\Desktop\assigment DSP\GUI\test1\recorded\recorded.flac', y, 8000);    
                    app.pathname2=('C:\Users\ossa7\Desktop\assigment DSP\GUI\test1\recorded');
                    app.importEditFieldtest.Value=app.pathname2;
        
            end


     app.testDatastore = audioDatastore(app.pathname2, 'IncludeSubfolders',true, ...
    'FileExtensions','.flac', ...
    'LabelSource','foldernames');


[y,Fs]=audioread(app.testDatastore.Files{1});    
order=7;
[b,a] = butter(order,1100/(Fs/2),'low');
filtered_sound1 = filter(b,a,y);
audiowrite('C:\Users\ossa7\Desktop\assigment DSP\GUI\filtered\filtered.flac', filtered_sound1, 48000);


plot(app.UIAxes2,filtered_sound1)

plot(app.UIAxes3,y)     
     
     
     player=audioplayer(y,Fs);
     info=audioinfo(app.testDatastore.Files{1});
     tD=0:seconds(1/Fs):seconds(info.Duration);
     tD=tD(1:end-1);
     cus=get(player,'CurrentSample');
     app.xxsecLabel.Text=char(tD(cus));
     plot(app.UIAxes,tD,y,'b',[-seconds(0.2) tD(end)],[0 0],'k',[tD(cus) tD(cus)],[min(min(y)) max(max(y))],'y')
xlim(app.UIAxes, [-seconds(0.2) tD(end)]);
ylim(app.UIAxes, [min(min(y)) max(max(y))]);

app.UIFigure.Visible='off';
app.UIFigure.Visible='on';
play(player)

cus=get(player,'CurrentSample');
     app.xxsecLabel.Text=char(tD(cus));
     
drawnow;
     plot(app.UIAxes,tD,y,'b',[-seconds(0.2) tD(end)],[0 0],'k',[tD(cus) tD(cus)],[min(min(y)) max(max(y))],'y')
xlim(app.UIAxes, [-seconds(0.2) tD(end)]);
ylim(app.UIAxes, [min(min(y)) max(max(y))]);

while tD(cus) ~= tD(end)
    cus=get(player,'CurrentSample');
     app.xxsecLabel.Text=char(tD(cus));
      plot(app.UIAxes,tD,y,'b',[-seconds(0.2) tD(end)],[0 0],'k',[tD(cus) tD(cus)],[min(min(y)) max(max(y))],'y')
xlim(app.UIAxes, [-seconds(0.2) tD(end)]);
ylim(app.UIAxes, [min(min(y)) max(max(y))]);
drawnow;
    
end

                    
        end

        % Button pushed function: guideButton
        function guideButtonPushed(app, event)
                        MESSAGE = sprintf(['HOW TO USE THIS PROGRAM\n\n1- ADD THE TRAINING FOLDER PATH TO THE SYSTEM DATABASE.\n\n2- CLICK ON TRAIN THE SYSTEM\n\n' ...
                '3- SELECT THE TESTING METHOD \n\n4- ADD THE TESTING FOLDER PATH\n\n5- CLICK ON THE IDENTIFY THE SPEAKER PUSH BUTTON\n\n'...
                '8- CLICK ON SETTING TAB \n\n THE SETTING TAB BE SHOW THE SYSTEM GUIDE']);
            msgbox(MESSAGE);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.Label_8 = uilabel(app.DatabaseTab);
            app.Label_8.Text = '';
            app.Button = uibutton(app.DatabaseTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'bros.jpg';
            app.Button.Text = '';
            app.Label_9 = uilabel(app.DatabaseTab);
            app.Label_9.Text = '';
            app.database = uitable(app.DatabaseTab);
            app.database.ColumnName = {'speaker name'; 'file loaction'};
            app.database.RowName = {};
            app.ImportEditFieldLabel = uilabel(app.DatabaseTab);
            app.ImportEditFieldLabel.Text = 'Import';
            app.ImportEditField = uieditfield(app.DatabaseTab, 'text');
            app.Label_10 = uilabel(app.DatabaseTab);
            app.Label_10.Text = '';
            app.DatabaseButton = uibutton(app.DatabaseTab, 'push');
            app.DatabaseButton.Icon = 'databnm.png';
            app.DatabaseButton.Text = 'Database';
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.Label_6 = uilabel(app.TrainingTab);
            app.Label_6.Text = '';
            app.TrainthesystemButton = uibutton(app.TrainingTab, 'push');
            app.TrainthesystemButton.ButtonPushedFcn = createCallbackFcn(app, @TrainthesystemButtonPushed, true);
            app.TrainthesystemButton.Text = 'Train the system';
            app.mfccandpoft = uitable(app.TrainingTab);
            app.mfccandpoft.ColumnName = {'Filename'; 'Pitch'; 'MFCC1'; 'MFCC2'; 'MFCC3'; 'MFCC4'; 'MFCC5'; 'MFCC6'; 'MFCC7'; 'MFCC8'; 'MFCC9'; 'MFCC10'; 'MFCC11'; 'MFCC12'; 'MFCC13'; 'Label'};
            app.mfccandpoft.RowName = {};
            app.Label = uilabel(app.TrainingTab);
            app.Label.Text = '%';
            app.ValidationaccuracyLabel = uilabel(app.TrainingTab);
            app.ValidationaccuracyLabel.Text = 'Validation accuracy';
            app.ValidationaccuracyEditField = uieditfield(app.TrainingTab, 'text');
            app.ValidationaccuracyEditField.Editable = 'off';
            app.Label_11 = uilabel(app.TrainingTab);
            app.Label_11.Text = '';
            app.FeatureExtractionusingPitchandMFCCLabel = uilabel(app.TrainingTab);
            app.FeatureExtractionusingPitchandMFCCLabel.Text = {'Feature Extraction using Pitch and MFCC'; ''};
            app.TestingTab = uitab(app.TabGroup);
            app.TestingTab.Title = 'Testing';
            app.UIAxes = uiaxes(app.TestingTab);
            title(app.UIAxes, 'Tested speaker voice')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Audio signal')
            app.Image = uiimage(app.TestingTab);
            app.Image.ImageSource = 'bio.jpg';
            app.Label_3 = uilabel(app.TestingTab);
            app.Label_3.Text = '';
            app.TestingMethodButtonGroup = uibuttongroup(app.TestingTab);
            app.TestingMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TestingMethodButtonGroupSelectionChanged, true);
            app.TestingMethodButtonGroup.Title = 'Testing Method';
            app.BrowseButton_2 = uiradiobutton(app.TestingMethodButtonGroup);
            app.BrowseButton_2.Text = 'Browse';
            app.BrowseButton_2.Value = true;
            app.RecordButton = uiradiobutton(app.TestingMethodButtonGroup);
            app.RecordButton.Text = 'Record';
            app.Label_4 = uilabel(app.TestingTab);
            app.Label_4.Text = '';
            app.ImportLabel = uilabel(app.TestingTab);
            app.ImportLabel.Text = 'Import:';
            app.importEditFieldtest = uieditfield(app.TestingTab, 'text');
            app.Label_5 = uilabel(app.TestingTab);
            app.Label_5.Text = '';
            app.UITable = uitable(app.TestingTab);
            app.UITable.ColumnName = {'Filename'; 'Testing speaker'; 'PredictedSpeaker'; 'ConfidencePercentage'};
            app.UITable.RowName = {};
            app.IdentifythespeakerButton = uibutton(app.TestingTab, 'push');
            app.IdentifythespeakerButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifythespeakerButtonPushed, true);
            app.IdentifythespeakerButton.Text = 'Identify the speaker';
            app.Label_2 = uilabel(app.TestingTab);
            app.Label_2.Text = '';
            app.xxsecLabel = uilabel(app.TestingTab);
            app.xxsecLabel.Text = 'xxsec';
            app.DurationLabel = uilabel(app.TestingTab);
            app.DurationLabel.Text = 'Duration :';
            app.Label_7 = uilabel(app.TestingTab);
            app.Label_7.Text = '';
            app.RecordingnotificationLabel = uilabel(app.TestingTab);
            app.RecordingnotificationLabel.Text = 'Recording notification';
            app.SelectButton = uibutton(app.TestingTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.settingTab = uitab(app.TabGroup);
            app.settingTab.Title = 'setting';
            app.UIAxes3 = uiaxes(app.settingTab);
            title(app.UIAxes3, 'the original tested signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes2 = uiaxes(app.settingTab);
            title(app.UIAxes2, 'the filtered tested signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Label_12 = uilabel(app.settingTab);
            app.Label_12.Text = '';
            app.guideButton = uibutton(app.settingTab, 'push');
            app.guideButton.ButtonPushedFcn = createCallbackFcn(app, @guideButtonPushed, true);
            app.guideButton.Icon = 'guide.jpg';
            app.guideButton.Text = 'guide';
            app.SpeakerIdentificationSystemLabel = uilabel(app.UIFigure);
            app.SpeakerIdentificationSystemLabel.Text = 'Speaker Identification System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end